classdef Start_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Tab4_EnableCheckBox           matlab.ui.control.CheckBox
        Tab3_EnableCheckBox           matlab.ui.control.CheckBox
        Tab2_EnableCheckBox           matlab.ui.control.CheckBox
        Tab1_EnableCheckBox           matlab.ui.control.CheckBox
        TabGroup                      matlab.ui.container.TabGroup
        Step1Tab                      matlab.ui.container.Tab
        DiscriptionLabel              matlab.ui.control.Label
        ImageNameTextArea             matlab.ui.control.TextArea
        ImageNameTextAreaLabel        matlab.ui.control.Label
        SelectDatasethereButton       matlab.ui.control.Button
        Tree                          matlab.ui.container.Tree
        NextStepButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Step2Tab                      matlab.ui.container.Tab
        UITable_2                     matlab.ui.control.Table
        SelectarectangletoconfirmButton  matlab.ui.control.StateButton
        ClickthepointtoconfirmButton  matlab.ui.control.StateButton
        UITable                       matlab.ui.control.Table
        NextStepButton_2              matlab.ui.control.Button
        LampForRW                     matlab.ui.control.Lamp
        SelectrearwallButton          matlab.ui.control.Button
        LampForVP                     matlab.ui.control.Lamp
        SelectVanishingpointButton    matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        Step3Tab                      matlab.ui.container.Tab
        NextStepButton_3              matlab.ui.control.Button
        LampForFG                     matlab.ui.control.Lamp
        SelectForegroundButton        matlab.ui.control.Button
        ResetButton_2                 matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        TourTab                       matlab.ui.container.Tab
        Slider_3                      matlab.ui.control.Slider
        Slider_3Label                 matlab.ui.control.Label
        Slider_2                      matlab.ui.control.Slider
        Slider_2Label                 matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        ResetButton_3                 matlab.ui.control.Button
        UIAxes_4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DEBUG_MODE = true;                         % if true, run debug mode

        image_path                                 % path of images
        image_names                                % the name list of the select dataset

        selected_image                             % path of the selected image
        selected_image_name                        % the name of the selected image
        selected_image_data;                       % the data of the selected image
        x_limit;                                   % The pixel limit in horizontal direction
        y_limit;                                   % The pixel limit in vertical direction
        basic_points = {0 0 0 0};
        basic_points_count = 0;

        select_point;                              
        VP;

        default_image_names = {'metro-station', 'oil-painting', ...
            'sagrada_familia', 'shopping-mall', 'simple-room', 'uhren-turm'};
    end
    
    methods (Access = private)

    end
    
%     methods (Access = public)
%         
%         function result = pushButtonCallback(app, n) 
%             app.UIAxses_2.HandleVisibility = 'callback';
%             result = ginput(n);
%             app.UIAxses_2.HandleVisibility = 'off';
%         end
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions'))
            
            % lock the interaction with difference highlight
            app.UIAxes.Interactions = [];

            % to make sure that button only visible after selecting image
            app.NextStepButton.Visible = "off";
            app.Step2Tab.HandleVisibility = "off";
        end

        % Button pushed function: SelectDatasethereButton
        function SelectDatasethereButtonPushed(app, event)
            GetDatasetList(app)
            % remove the cover
            % app.Image.Visible = false;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(selectedNodes.NodeData)
                % save the selected dataset path
                app.selected_image = selectedNodes.NodeData;
                app.selected_image_data = imread(selectedNodes.NodeData);
                % get the dataset 
                % get the dataset name
                [~,dirname,~] = fileparts(selectedNodes.NodeData);
                app.selected_image_name = dirname;
%                 % picture processing
%                 ProcessImageData_final(app)
%                 
%                 % difference highlight initialization
%                 DifferenceInitilization(app)
%                 % curtain initialization
%                 CurtainInitialization(app)
%                 % time lapse initialization
%                 TimelapseInitialization(app)
%                 
                % description
                [app.y_limit,app.x_limit,~] = size(app.selected_image_data);
                
                app.ImageNameTextArea.Value = app.selected_image_name;
                imshow(app.selected_image, 'Parent', app.UIAxes);
                imshow(app.selected_image, 'Parent', app.UIAxes_2);
                imshow(app.selected_image, 'Parent', app.UIAxes_3);
                
                app.UIAxes.YLim = [0 app.y_limit];
                app.UIAxes.XLim = [0 app.x_limit];

                app.UIAxes_2.YLim = [0 app.y_limit];
                app.UIAxes_2.XLim = [0 app.x_limit];

                app.UIAxes_3.YLim = [0 app.y_limit];
                app.UIAxes_3.XLim = [0 app.x_limit];

                % show the next setp button
                app.NextStepButton.Visible = "on";
                app.Step2Tab.HandleVisibility = "on";

                % app.PictureNumberEditField.Value = length(app.current_image_names);
                
            else
                
            end
        end

        % Button down function: UIAxes_2
        function UIAxesButtonDown(app, event)
            cursor_position = array2table(app.UIAxes_2.CurrentPoint);
            
%             app.UITable.data=cursor_position;
%             app.basic_points_count = app.basic_points_count + 1;
%             app.basic_points(app.basic_points_count) = event.IntersectionPoint 
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Step2Tab;
            app.Tab2_EnableCheckBox.Value = true;
        end

        % Button pushed function: NextStepButton_2
        function NextStepButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Step3Tab;
            
        end

        % Button pushed function: NextStepButton_3
        function NextStepButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.TourTab;
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)

        end

        % Callback function
        function ResetButtonPushed(app, event)
            cursor_position = array2table(app.UIAxes_2.CurrentPoint);
            app.UITable.Data = cursor_position;
        end

        % Button pushed function: SelectVanishingpointButton
        function SelectVanishingpointButtonPushed(app, event)
            app.LampForVP.Color = 'r';
            app.SelectVanishingpointButton.Visible = 'off';
            app.SelectVanishingpointButton.Enable = 'off';
            
            app.ClickthepointtoconfirmButton.Visible = 'on';
            app.ClickthepointtoconfirmButton.Enable = 'on';
%             rectange = drawrectangle(app.UIAxes_2);
            app.select_point = drawpoint(app.UIAxes_2);


%             while 1
% %                 app.UITable.Data = [];
            app.UITable.Data = array2table(round(app.select_point.Position));
% %                 if app.ClickthepointtoconfirmButton.Value == 1
%                     break;
%                 end

%             end
%             app.VP = round(app.select_point.Position);
%             app.Confirm = 0;
            app.ClickthepointtoconfirmButton.Visible = 'off';
            app.ClickthepointtoconfirmButton.Enable = 'off';
            app.SelectVanishingpointButton.Visible = 'on';
            app.SelectVanishingpointButton.Enable = 'on';
            app.LampForVP.Color = 'g';
%             app.UIFigure.HandleVisibility = 'callback';
%             result = ginput(4);
%             [xi,yi] = getpts(app.UIAxes)
%             app.UIFigure.HandleVisibility = 'off';
%             app.UITable.Data = [xi,yi]
%             app.UITable.Data = array2table(result);


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox
            % Each "case" is the title of a Tab and contains the pair checkbox.
            switch selectedTab.Title
                case 'Step 1'
                    checkboxValue = app.Tab1_EnableCheckBox.Value;
                case 'Step 2'
                    checkboxValue = app.Tab2_EnableCheckBox.Value;
                case 'Step3'
                    checkboxValue = app.Tab3_EnableCheckBox.Value;
                case 'Tour'
                    checkboxValue = app.Tab4_EnableCheckBox.Value;
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end

            % If paired checkbox is checked, return to the previously selected tab
            if ~checkboxValue
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Button pushed function: SelectrearwallButton
        function SelectrearwallButtonPushed(app, event)
            app.LampForRW.Color = 'r';
            app.SelectrearwallButton.Visible = 'off';
            app.SelectrearwallButton.Enable = 'off';
            app.SelectarectangletoconfirmButton.Visible = 'on';
            app.SelectarectangletoconfirmButton.Enable = 'on';
            select_rectange = drawrectangle(app.UIAxes_2);
%             app.select_point = drawpoint(app.UIAxes_2);
            coor = select_rectange.Position;
%             [xmin, ymin, width, height]
            xmin = coor(1);
            ymin = coor(2);
            width = coor(3);
            height = coor(4);
            rearWall7 = [xmin, ymin];
            rearWall8 = [xmin+width, ymin];
            rearWall1 = [xmin, ymin+height];
            rearWall2 = [xmin+width, ymin+height];

            rearWall = round([rearWall7; rearWall8; rearWall1; rearWall2]);
            app.UITable_2.Data = array2table(rearWall);


%             while 1
% %                 app.UITable.Data = [];
%             app.UITable.Data = array2table(round(app.select_point.Position));
%                 if app.ClickthepointtoconfirmButton.Value == 1
%                     break;
%                 end

%             end
            app.SelectrearwallButton.Visible = 'on';
            app.SelectrearwallButton.Enable = 'on';
            app.SelectarectangletoconfirmButton.Visible = 'off';
            app.SelectarectangletoconfirmButton.Enable = 'off';
            app.LampForRW.Color = 'g';
%             app.UIFigure.HandleVisibility = 'c
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step 1';
            app.UIAxes = uiaxes(app.Step1Tab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'axes1';
            app.NextStepButton = uibutton(app.Step1Tab, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.Tree = uitree(app.Step1Tab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SelectDatasethereButton = uibutton(app.Step1Tab, 'push');
            app.SelectDatasethereButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatasethereButtonPushed, true);
            app.SelectDatasethereButton.Text = 'Select Dataset here';
            app.ImageNameTextAreaLabel = uilabel(app.Step1Tab);
            app.ImageNameTextAreaLabel.Text = 'Image Name: ';
            app.ImageNameTextArea = uitextarea(app.Step1Tab);
            app.ImageNameTextArea.Editable = 'off';
            app.DiscriptionLabel = uilabel(app.Step1Tab);
            app.DiscriptionLabel.Text = 'Discription:';
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.Title = 'Step 2';
            app.UIAxes_2 = uiaxes(app.Step2Tab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2.Tag = 'axes1';
            app.SelectVanishingpointButton = uibutton(app.Step2Tab, 'push');
            app.SelectVanishingpointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVanishingpointButtonPushed, true);
            app.SelectVanishingpointButton.Text = 'Select Vanishing point';
            app.LampForVP = uilamp(app.Step2Tab);
            app.SelectrearwallButton = uibutton(app.Step2Tab, 'push');
            app.SelectrearwallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectrearwallButtonPushed, true);
            app.SelectrearwallButton.Text = 'Select rear wall';
            app.LampForRW = uilamp(app.Step2Tab);
            app.NextStepButton_2 = uibutton(app.Step2Tab, 'push');
            app.NextStepButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_2Pushed, true);
            app.NextStepButton_2.Text = 'Next Step';
            app.UITable = uitable(app.Step2Tab);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {'VP'; 'RW'};
            app.ClickthepointtoconfirmButton = uibutton(app.Step2Tab, 'state');
            app.ClickthepointtoconfirmButton.Enable = 'off';
            app.ClickthepointtoconfirmButton.Visible = 'off';
            app.ClickthepointtoconfirmButton.Text = 'Click the point to confirm';
            app.SelectarectangletoconfirmButton = uibutton(app.Step2Tab, 'state');
            app.SelectarectangletoconfirmButton.Enable = 'off';
            app.SelectarectangletoconfirmButton.Visible = 'off';
            app.SelectarectangletoconfirmButton.Text = 'Select a rectangle to confirm';
            app.UITable_2 = uitable(app.Step2Tab);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {'7'; '8'; '1'; '2'};
            app.Step3Tab = uitab(app.TabGroup);
            app.Step3Tab.Title = 'Step3';
            app.UIAxes_3 = uiaxes(app.Step3Tab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Tag = 'axes1';
            app.ResetButton_2 = uibutton(app.Step3Tab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.SelectForegroundButton = uibutton(app.Step3Tab, 'push');
            app.SelectForegroundButton.Text = 'Select Foreground';
            app.LampForFG = uilamp(app.Step3Tab);
            app.NextStepButton_3 = uibutton(app.Step3Tab, 'push');
            app.NextStepButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_3Pushed, true);
            app.NextStepButton_3.Text = 'Next Step';
            app.TourTab = uitab(app.TabGroup);
            app.TourTab.Title = 'Tour';
            app.UIAxes_4 = uiaxes(app.TourTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Tag = 'axes1';
            app.ResetButton_3 = uibutton(app.TourTab, 'push');
            app.ResetButton_3.Text = 'Reset';
            app.SliderLabel = uilabel(app.TourTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.TourTab);
            app.Slider_2Label = uilabel(app.TourTab);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.TourTab);
            app.Slider_3Label = uilabel(app.TourTab);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.TourTab);
            app.Tab1_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab1_EnableCheckBox.Text = 'Tab1_Enable';
            app.Tab1_EnableCheckBox.Value = true;
            app.Tab2_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab2_EnableCheckBox.Text = 'Tab2_Enable';
            app.Tab3_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab3_EnableCheckBox.Text = 'Tab3_Enable';
            app.Tab4_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab4_EnableCheckBox.Text = 'Tab4_Enable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end